classdef GuiApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SacarMatriculaTab               matlab.ui.container.Tab
        SeleccionarredDropDown          matlab.ui.control.DropDown
        SeleccionarredDropDownLabel     matlab.ui.control.Label
        GirarimagenButton               matlab.ui.control.Button
        TextomatriculaTextArea          matlab.ui.control.TextArea
        TextomatriculaTextAreaLabel     matlab.ui.control.Label
        SeleccionarimagenButton         matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        EjecutarButton                  matlab.ui.control.Button
        MatrculadetectadaPanel          matlab.ui.container.Panel
        Image3                          matlab.ui.control.Image
        SacarmatrculadecochesLabel      matlab.ui.control.Label
        EntrenarAlexNetTab              matlab.ui.container.Tab
        PLAYButton_2                    matlab.ui.control.Button
        RESETButton_2                   matlab.ui.control.Button
        VerboseEditField_2              matlab.ui.control.EditField
        VerboseEditField_2Label         matlab.ui.control.Label
        ValidationPatienceEditField_2   matlab.ui.control.EditField
        ValidationPatienceEditField_2Label  matlab.ui.control.Label
        ValidationFrequencyEditField_2  matlab.ui.control.NumericEditField
        ValidationFrequencyEditField_2Label  matlab.ui.control.Label
        ValidationDataEditField_2       matlab.ui.control.EditField
        ValidationDataEditField_2Label  matlab.ui.control.Label
        InitialLearnRateEditField_2     matlab.ui.control.NumericEditField
        InitialLearnRateEditField_2Label  matlab.ui.control.Label
        MaxEpochsEditField_2            matlab.ui.control.NumericEditField
        MaxEpochsEditField_2Label       matlab.ui.control.Label
        EntrenarredAlexNetLabel         matlab.ui.control.Label
        MiniBachtSizeEditField          matlab.ui.control.NumericEditField
        MiniBachtSizeEditFieldLabel     matlab.ui.control.Label
        MtododeoptimizacinDropDown      matlab.ui.control.DropDown
        MtododeoptimizacinDropDownLabel  matlab.ui.control.Label
        EntrenarGoogleNetTab            matlab.ui.container.Tab
        RESETButton                     matlab.ui.control.Button
        PLAYButton                      matlab.ui.control.Button
        AjustesPanel                    matlab.ui.container.Panel
        MiniBatchSizeEditField          matlab.ui.control.NumericEditField
        MiniBatchSizeEditFieldLabel     matlab.ui.control.Label
        MtododeoptimizacinDropDown_2    matlab.ui.control.DropDown
        MtododeoptimizacinDropDown_2Label  matlab.ui.control.Label
        VerboseEditField                matlab.ui.control.EditField
        VerboseEditFieldLabel           matlab.ui.control.Label
        ValidationPatienceEditField     matlab.ui.control.EditField
        ValidationPatienceEditFieldLabel  matlab.ui.control.Label
        ValidationFrequencyEditField    matlab.ui.control.NumericEditField
        ValidationFrequencyEditFieldLabel  matlab.ui.control.Label
        ValidationDataEditField         matlab.ui.control.EditField
        ValidationDataEditFieldLabel    matlab.ui.control.Label
        InitialLearnRateEditField       matlab.ui.control.NumericEditField
        InitialLearnRateEditFieldLabel  matlab.ui.control.Label
        MaxEpochsEditField              matlab.ui.control.NumericEditField
        MaxEpochsEditFieldLabel         matlab.ui.control.Label
        EntrenarredGoogleNetLabel       matlab.ui.control.Label
        SimuladorparkingTab             matlab.ui.container.Tab
        SalirparkingButton              matlab.ui.control.Button
        EntrarparkingButton             matlab.ui.control.Button
        MeterfotodelcochePanel          matlab.ui.container.Panel
        EjecutarButton_2                matlab.ui.control.Button
        GirarimagenButton_2             matlab.ui.control.Button
        SeleccionarimagenButton_2       matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        SimuladorparkingLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        ImageFile; % Description
        ImagenSeleccionada = false;
        ImageFile2; % Description
        ImagenSeleccionada2 = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            if app.ImagenSeleccionada
                [IMat, textoMat] = GetMatricula(app.ImageFile, app.SeleccionarredDropDown.Value);
                app.TextomatriculaTextArea.Value = textoMat;
                app.Image3.ImageSource = IMat;
                %{
                hold(app.ContainerVideo, 'on'); 
                text(XSupDcha+100,YSupDcha,texto,'Color','y','FontSize',10,'FontWeight','bold')
line([XSupIzda,XSupDcha],[YSupIzda,YSupDcha],'LineWidth',2,'Color',color)
line([XSupIzda,XInfIzda],[YSupIzda,YInfIzda],'LineWidth',2,'Color',color)
line([XSupDcha,XInfDcha],[YSupDcha,YInfDcha],'LineWidth',2,'Color',color)
line([XInfIzda,XInfDcha],[YInfIzda,YInfDcha],'LineWidth',2,'Color',color)
hold(app.ContainerVideo, 'off');
                %}
            else
                msgbox("No hay ninguna imagen seleccionada.");
            end
        end

        % Button pushed function: SeleccionarimagenButton
        function SeleccionarimagenButtonPushed(app, event)
            [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           fullname = [filepath, filename];
           app.ImageFile = imread(fullname);
           app.Image.ImageSource = app.ImageFile;
           app.ImagenSeleccionada = true;
           %ImagenEntrada.ImageSource = app.ImageFile;
           %imagesc(GuiApp.ImagenEntrada.so, ImageFile);
        end

        % Button pushed function: GirarimagenButton
        function GirarimagenButtonPushed(app, event)
            app.ImageFile = imrotate(app.ImageFile,-90,'bilinear');
            app.Image.ImageSource = app.ImageFile;
        end

        % Button pushed function: GirarimagenButton_2
        function GirarimagenButton_2Pushed(app, event)
            app.ImageFile2 = imrotate(app.ImageFile2,-90,'bilinear');
            app.Image4.ImageSource = app.ImageFile2;
        end

        % Button pushed function: SeleccionarimagenButton_2
        function SeleccionarimagenButton_2Pushed(app, event)
            [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           fullname = [filepath, filename];
           app.ImageFile2 = imread(fullname);
           app.Image4.ImageSource = app.ImageFile2;
           app.ImagenSeleccionada2 = true;
        end

        % Button pushed function: EjecutarButton_2
        function EjecutarButton_2Pushed(app, event)
            if app.ImagenSeleccionada2
                [~, textoMat] = GetMatricula(app.ImageFile2, app.SeleccionarredDropDown.Value);
                app.Label.Text = textoMat;
                %{
                hold(app.ContainerVideo, 'on'); 
                text(XSupDcha+100,YSupDcha,texto,'Color','y','FontSize',10,'FontWeight','bold')
line([XSupIzda,XSupDcha],[YSupIzda,YSupDcha],'LineWidth',2,'Color',color)
line([XSupIzda,XInfIzda],[YSupIzda,YInfIzda],'LineWidth',2,'Color',color)
line([XSupDcha,XInfDcha],[YSupDcha,YInfDcha],'LineWidth',2,'Color',color)
line([XInfIzda,XInfDcha],[YInfIzda,YInfDcha],'LineWidth',2,'Color',color)
hold(app.ContainerVideo, 'off');
                %}
            else
                msgbox("No hay ninguna imagen seleccionada.");
            end
        end

        % Button pushed function: EntrarparkingButton
        function EntrarparkingButtonPushed(app, event)
            enterParking(app.Label.Text);
        end

        % Button pushed function: SalirparkingButton
        function SalirparkingButtonPushed(app, event)
            leaveParking(app.Label.Text);
        end

        % Button pushed function: RESETButton_2
        function RESETButton_2Pushed(app, event)
            %alex net
            app.MiniBachtSizeEditField.Value = 10;
            app.MaxEpochsEditField_2.Value = 4;
            app.InitialLearnRateEditField_2.Value = 1e-4;
            app.ValidationDataEditField_2.Value = 'imdsValidation';
            app.ValidationFrequencyEditField_2.Value = 3;
            app.ValidationPatienceEditField_2.Value = 'Inf';
            app.VerboseEditField_2.Value = 'false';
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            %GOOGLE NET
            app.MiniBatchSizeEditField.Value = 10;
            app.MaxEpochsEditField.Value = 4;
            app.InitialLearnRateEditField.Value = 1e-4;
            app.ValidationDataEditField.Value = 'imdsValidation';
            app.ValidationFrequencyEditField.Value = 3;
            app.ValidationPatienceEditField.Value = 'Inf';
            app.VerboseEditField.Value = 'false';
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            % play de GOOGLE NET
            mOpt = app.MtododeoptimizacinDropDown_2.Value;
            miniB = app.MiniBatchSizeEditField.Value;
            maxE = app.MaxEpochsEditField.Value;
            init = app.InitialLearnRateEditField.Value;
            vData = app.ValidationDataEditField.Value;
            vFreq = app.ValidationFrequencyEditField.Value;
            vPat = app.ValidationPatienceEditField.Value;
            verbose = app.VerboseEditField.Value;
            EntrenarGNParametros(mOpt, miniB, maxE, init, vData, vFreq, vPat, verbose)
        end

        % Button pushed function: PLAYButton_2
        function PLAYButton_2Pushed(app, event)
            % play de ALexNet
            mOpt = app.MtododeoptimizacinDropDown.Value;
            miniB = app.MiniBachtSizeEditField.Value;
            maxE = app.MaxEpochsEditField_2.Value;
            init = app.InitialLearnRateEditField_2.Value;
            vData = app.ValidationDataEditField_2.Value;
            vFreq = app.ValidationFrequencyEditField_2.Value;
            vPat = app.ValidationPatienceEditField_2.Value;
            verbose = app.VerboseEditField_2.Value;
            EntrenarANParametros(mOpt, miniB, maxE, init, vData, vFreq, vPat, verbose)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SacarMatriculaTab = uitab(app.TabGroup);
            app.SacarMatriculaTab.Title = 'Sacar Matricula';
            app.SacarmatrculadecochesLabel = uilabel(app.SacarMatriculaTab);
            app.SacarmatrculadecochesLabel.Text = 'Sacar matrícula de coches';
            app.MatrculadetectadaPanel = uipanel(app.SacarMatriculaTab);
            app.MatrculadetectadaPanel.Title = 'Matrícula detectada';
            app.Image3 = uiimage(app.MatrculadetectadaPanel);
            app.EjecutarButton = uibutton(app.SacarMatriculaTab, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.Image = uiimage(app.SacarMatriculaTab);
            app.Image.Tag = 'ImagenEntrada';
            app.SeleccionarimagenButton = uibutton(app.SacarMatriculaTab, 'push');
            app.SeleccionarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarimagenButtonPushed, true);
            app.SeleccionarimagenButton.Text = 'Seleccionar imagen';
            app.TextomatriculaTextAreaLabel = uilabel(app.SacarMatriculaTab);
            app.TextomatriculaTextAreaLabel.Text = 'Texto matricula';
            app.TextomatriculaTextArea = uitextarea(app.SacarMatriculaTab);
            app.GirarimagenButton = uibutton(app.SacarMatriculaTab, 'push');
            app.GirarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @GirarimagenButtonPushed, true);
            app.GirarimagenButton.Text = 'Girar imagen';
            app.SeleccionarredDropDownLabel = uilabel(app.SacarMatriculaTab);
            app.SeleccionarredDropDownLabel.Text = 'Seleccionar red';
            app.SeleccionarredDropDown = uidropdown(app.SacarMatriculaTab);
            app.SeleccionarredDropDown.Items = {'alexnet', 'googlenet'};
            app.SeleccionarredDropDown.Value = 'alexnet';
            app.EntrenarAlexNetTab = uitab(app.TabGroup);
            app.EntrenarAlexNetTab.Title = 'Entrenar AlexNet';
            app.MtododeoptimizacinDropDownLabel = uilabel(app.EntrenarAlexNetTab);
            app.MtododeoptimizacinDropDownLabel.Text = 'Método de optimización';
            app.MtododeoptimizacinDropDown = uidropdown(app.EntrenarAlexNetTab);
            app.MtododeoptimizacinDropDown.Items = {'sgdm', 'adam'};
            app.MtododeoptimizacinDropDown.Value = 'sgdm';
            app.MiniBachtSizeEditFieldLabel = uilabel(app.EntrenarAlexNetTab);
            app.MiniBachtSizeEditFieldLabel.Text = 'MiniBachtSize';
            app.MiniBachtSizeEditField = uieditfield(app.EntrenarAlexNetTab, 'numeric');
            app.MiniBachtSizeEditField.Limits = [0 Inf];
            app.MiniBachtSizeEditField.Value = 10;
            app.EntrenarredAlexNetLabel = uilabel(app.EntrenarAlexNetTab);
            app.EntrenarredAlexNetLabel.Text = 'Entrenar red AlexNet';
            app.MaxEpochsEditField_2Label = uilabel(app.EntrenarAlexNetTab);
            app.MaxEpochsEditField_2Label.Text = 'MaxEpochs';
            app.MaxEpochsEditField_2 = uieditfield(app.EntrenarAlexNetTab, 'numeric');
            app.InitialLearnRateEditField_2Label = uilabel(app.EntrenarAlexNetTab);
            app.InitialLearnRateEditField_2Label.Text = 'InitialLearnRate';
            app.InitialLearnRateEditField_2 = uieditfield(app.EntrenarAlexNetTab, 'numeric');
            app.ValidationDataEditField_2Label = uilabel(app.EntrenarAlexNetTab);
            app.ValidationDataEditField_2Label.Text = 'ValidationData';
            app.ValidationDataEditField_2 = uieditfield(app.EntrenarAlexNetTab, 'text');
            app.ValidationFrequencyEditField_2Label = uilabel(app.EntrenarAlexNetTab);
            app.ValidationFrequencyEditField_2Label.Text = 'ValidationFrequency';
            app.ValidationFrequencyEditField_2 = uieditfield(app.EntrenarAlexNetTab, 'numeric');
            app.ValidationPatienceEditField_2Label = uilabel(app.EntrenarAlexNetTab);
            app.ValidationPatienceEditField_2Label.Text = 'ValidationPatience';
            app.ValidationPatienceEditField_2 = uieditfield(app.EntrenarAlexNetTab, 'text');
            app.VerboseEditField_2Label = uilabel(app.EntrenarAlexNetTab);
            app.VerboseEditField_2Label.Text = 'Verbose';
            app.VerboseEditField_2 = uieditfield(app.EntrenarAlexNetTab, 'text');
            app.RESETButton_2 = uibutton(app.EntrenarAlexNetTab, 'push');
            app.RESETButton_2.ButtonPushedFcn = createCallbackFcn(app, @RESETButton_2Pushed, true);
            app.RESETButton_2.Text = 'RESET';
            app.PLAYButton_2 = uibutton(app.EntrenarAlexNetTab, 'push');
            app.PLAYButton_2.ButtonPushedFcn = createCallbackFcn(app, @PLAYButton_2Pushed, true);
            app.PLAYButton_2.Text = 'PLAY';
            app.EntrenarGoogleNetTab = uitab(app.TabGroup);
            app.EntrenarGoogleNetTab.Title = 'Entrenar GoogleNet';
            app.EntrenarredGoogleNetLabel = uilabel(app.EntrenarGoogleNetTab);
            app.EntrenarredGoogleNetLabel.Text = {'Entrenar red GoogleNet'; ''};
            app.AjustesPanel = uipanel(app.EntrenarGoogleNetTab);
            app.AjustesPanel.Title = 'Ajustes';
            app.MaxEpochsEditFieldLabel = uilabel(app.AjustesPanel);
            app.MaxEpochsEditFieldLabel.Text = 'MaxEpochs';
            app.MaxEpochsEditField = uieditfield(app.AjustesPanel, 'numeric');
            app.InitialLearnRateEditFieldLabel = uilabel(app.AjustesPanel);
            app.InitialLearnRateEditFieldLabel.Text = 'InitialLearnRate';
            app.InitialLearnRateEditField = uieditfield(app.AjustesPanel, 'numeric');
            app.ValidationDataEditFieldLabel = uilabel(app.AjustesPanel);
            app.ValidationDataEditFieldLabel.Text = 'ValidationData';
            app.ValidationDataEditField = uieditfield(app.AjustesPanel, 'text');
            app.ValidationFrequencyEditFieldLabel = uilabel(app.AjustesPanel);
            app.ValidationFrequencyEditFieldLabel.Text = 'ValidationFrequency';
            app.ValidationFrequencyEditField = uieditfield(app.AjustesPanel, 'numeric');
            app.ValidationPatienceEditFieldLabel = uilabel(app.AjustesPanel);
            app.ValidationPatienceEditFieldLabel.Text = 'ValidationPatience';
            app.ValidationPatienceEditField = uieditfield(app.AjustesPanel, 'text');
            app.VerboseEditFieldLabel = uilabel(app.AjustesPanel);
            app.VerboseEditFieldLabel.Text = 'Verbose';
            app.VerboseEditField = uieditfield(app.AjustesPanel, 'text');
            app.MtododeoptimizacinDropDown_2Label = uilabel(app.AjustesPanel);
            app.MtododeoptimizacinDropDown_2Label.Text = 'Método de optimización';
            app.MtododeoptimizacinDropDown_2 = uidropdown(app.AjustesPanel);
            app.MtododeoptimizacinDropDown_2.Items = {'sgdm', 'adam'};
            app.MtododeoptimizacinDropDown_2.Value = 'sgdm';
            app.MiniBatchSizeEditFieldLabel = uilabel(app.AjustesPanel);
            app.MiniBatchSizeEditFieldLabel.Text = 'MiniBatchSize';
            app.MiniBatchSizeEditField = uieditfield(app.AjustesPanel, 'numeric');
            app.PLAYButton = uibutton(app.EntrenarGoogleNetTab, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.RESETButton = uibutton(app.EntrenarGoogleNetTab, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.SimuladorparkingTab = uitab(app.TabGroup);
            app.SimuladorparkingTab.Title = 'Simulador parking';
            app.SimuladorparkingLabel = uilabel(app.SimuladorparkingTab);
            app.SimuladorparkingLabel.Text = 'Simulador parking';
            app.MeterfotodelcochePanel = uipanel(app.SimuladorparkingTab);
            app.MeterfotodelcochePanel.Title = 'Meter foto del coche';
            app.Image4 = uiimage(app.MeterfotodelcochePanel);
            app.Label = uilabel(app.MeterfotodelcochePanel);
            app.Label.Text = '-';
            app.SeleccionarimagenButton_2 = uibutton(app.MeterfotodelcochePanel, 'push');
            app.SeleccionarimagenButton_2.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarimagenButton_2Pushed, true);
            app.SeleccionarimagenButton_2.Text = 'Seleccionar imagen';
            app.GirarimagenButton_2 = uibutton(app.MeterfotodelcochePanel, 'push');
            app.GirarimagenButton_2.ButtonPushedFcn = createCallbackFcn(app, @GirarimagenButton_2Pushed, true);
            app.GirarimagenButton_2.Text = 'Girar imagen';
            app.EjecutarButton_2 = uibutton(app.MeterfotodelcochePanel, 'push');
            app.EjecutarButton_2.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButton_2Pushed, true);
            app.EjecutarButton_2.Text = 'Ejecutar';
            app.EntrarparkingButton = uibutton(app.SimuladorparkingTab, 'push');
            app.EntrarparkingButton.ButtonPushedFcn = createCallbackFcn(app, @EntrarparkingButtonPushed, true);
            app.EntrarparkingButton.Text = 'Entrar parking';
            app.SalirparkingButton = uibutton(app.SimuladorparkingTab, 'push');
            app.SalirparkingButton.ButtonPushedFcn = createCallbackFcn(app, @SalirparkingButtonPushed, true);
            app.SalirparkingButton.Text = 'Salir parking';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end